classdef GUI_muscle_synergies < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MusclesynergybasedanalysisGUIUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        CalculatemusclesynergiesButton  matlab.ui.control.Button
        fileLabel                       matlab.ui.control.Label
        OutputsButtonGroup              matlab.ui.container.ButtonGroup
        EstimateHButton                 matlab.ui.control.RadioButton
        EstimateWButton                 matlab.ui.control.RadioButton
        BothButton                      matlab.ui.control.RadioButton
        OUTPUTSLabel                    matlab.ui.control.Label
        TotalAbsdiffLabel               matlab.ui.control.Label
        TotalSSVLabel                   matlab.ui.control.Label
        TotalEDLabel                    matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        SSVWestTab                      matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        DiffWestTab                     matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        EDHestTab                       matlab.ui.container.Tab
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        COSTFUNCTIONLabel               matlab.ui.control.Label
        Objectivefunction               matlab.ui.control.Label
        next_output                     matlab.ui.control.Label
        ForceSpinnerLabel               matlab.ui.control.Label
        ForceSpinner                    matlab.ui.control.Spinner
        NEXTVALUELabel                  matlab.ui.control.Label
        FOROPTIMIZATIONLabel            matlab.ui.control.Label
        gd_optCheckBox                  matlab.ui.control.CheckBox
        LoadreferencesynergiesMenu      matlab.ui.container.Menu
        LoadnewtrialMenu                matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        data 
        selection = 'Both';
        mean_W_gs
        mean_H_gs
        names_muscles
        iteration = 0;
        dtw1
        error1
        ssv1
        forceValues = [];
        of_values = []; % Objective function values
        fs_emg
        fs_acc
        max_tp
        max_pect
        max_dant
        max_dmed
        max_dpos
        max_bb
        max_tr
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadnewtrialMenu
        function LoadnewtrialMenuSelected(app, event)
            % Prompt user to select a file
            [filename, filepath] = uigetfile({'*.csv'}, 'Select Data File');
                       
            % Check if the user canceled file selection
            if isequal(filename, 0)
                % User canceled, do nothing
                return;
            end
            
            % Construct the full file path
            emg_filepath = fullfile(filepath, filename);
            
            %------------- Read the file
            datos = readtable(emg_filepath, 'VariableNamesLine',4, 'Delimiter',',');
            %------------- Find the sRate
            srate = readcell(emg_filepath, 'Range', 'A7:T7'); srate = unique(srate);
            srate1 = cell2mat (textscan(srate{1},'%f')); srate2 = cell2mat (textscan(srate{2},'%f'));
            if srate1 > srate2
                app.fs_emg = round(srate1); app.fs_acc = round(srate2);
            else
                app.fs_emg = round(srate2); app.fs_acc = round(srate1);
            end
            disp(app.fs_emg)
            disp(app.fs_acc)
            %------------ Confirm that data was updated
            app.fileLabel.Text = sprintf("%s was loaded",filename);
            app.fileLabel.FontWeight = 'bold';
            app.data = datos;
            disp('CSV loaded')
        end

        % Selection changed function: OutputsButtonGroup
        function OutputsButtonGroupSelectionChanged(app, event)
            selectedButton = app.OutputsButtonGroup.SelectedObject;
            app.selection = selectedButton.Text;
            
        end

        % Button pushed function: CalculatemusclesynergiesButton
        function CalculatesynergiesButtonPushed(app, event)
            if app.gd_optCheckBox.Value == 1
                app.iteration = app.iteration +1;
                disp(app.iteration)
                app.next_output.Enable = 'on';
            else
                app.iteration = 0;
                app.forceValues = [];
                app.of_values = []; 
                app.next_output.Enable = 'off';
                app.next_output.Text = sprintf("%.2f",0);
            end
            
            % Empty the plots
            cla(app.UIAxes_4);
            cla(app.UIAxes_5);
            cla(app.UIAxes_6);
            % Calculate muscle synergies
            [W,H,~,emg,VAF,mean_emg,H_all,W_all,VAF2] = processing_EMG_signals(app.data,0,app.fs_emg,app.fs_acc);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %----- Estimate W using H_ref -----%
            estimated_W = pinv(app.mean_H_gs) * mean_emg;
            %----- Normalize est_W to a length of 1
            estimated_W = normc(abs(estimated_W)');
            %----- Calculate  the cosine similarity of synergy vectors (ssv)
            ssv = app.mean_W_gs' * estimated_W;
            %----- Calculate the absolute difference
            abs_diff = round(estimated_W,2) - round(app.mean_W_gs,2);
            %%%%% Visualization %%%%%
            %----- Color code of the ssv
            imagesc(app.UIAxes,ssv)
            colormap(app.UIAxes,'jet');  % Colormap
            colorbar(app.UIAxes);
            %----- Absolute difference in bars
            b_plot=bar(app.UIAxes_2,abs_diff');
            legend(app.UIAxes_2, cellstr(app.names_muscles));
            xticklabels(app.UIAxes_2, {'syn1', 'syn2', 'syn3'});  % Custom x-axis labels
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %----- Estimate H using W_ref -----%
            estimated_H = mean_emg * pinv(app.mean_W_gs');
            %----- Calculate the euclidean distance between est_H and
            %----- mean_H at each time point
            eu_dist = sqrt(sum((estimated_H - app.mean_H_gs).^2, 1));
            %%%%% Visualization %%%%%
            %----- Plot the mean and estimated activation coefficients in
            %----- the same plot for comparison
            plot(app.UIAxes_4, app.mean_H_gs(:,1));
            hold(app.UIAxes_4, 'on');  % Hold the plot to overlay the next plot
            plot(app.UIAxes_4, estimated_H(:,1));
            legend(app.UIAxes_4,{'GS','Estimated'})
            
            plot(app.UIAxes_5, app.mean_H_gs(:,2));
            hold(app.UIAxes_5, 'on');  % Hold the plot to overlay the next plot
            plot(app.UIAxes_5, estimated_H(:,2));
%             legend(app.UIAxes_5,{'GS','Estimated'})
            
            plot(app.UIAxes_6, app.mean_H_gs(:,3));
            hold(app.UIAxes_6, 'on');  % Hold the plot to overlay the next plot
            plot(app.UIAxes_6, estimated_H(:,3));
%             legend(app.UIAxes_6,{'GS','Estimated'})
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%% Variables %%%%%%%%%%%%
            %----- Calculate relevant outputs
            total_eu_dist = sum(sum(eu_dist));
            total_abs_diff = sum(sum(abs(abs_diff)));
            total_ssv = sum(max(ssv,[],2));
            
            %---- Calculate the output of the cost (objective) function
            g1 = 1.4; g2 = -1.8; 
            cf = g1 * total_eu_dist + g2 * total_ssv;
%             cf = g1 * total_abs_diff + g2 * total_ssv;
            currentForceValue = app.ForceSpinner.Value;
            %----- If we want to do the optimization (gradient descent)
            if app.gd_optCheckBox.Value == 1
                %----- Save the force values in a vector
                app.forceValues(end+1) = currentForceValue;
                app.of_values(end+1) = cf;
                %----- To execute the optimization at least 2 values are
                %----- necessary
                if app.iteration > 1
                  %----- Calculate the next value using the gradient
                  %----- descent optimization 
                  next_value = gradient_d(app.of_values(end-1:end), app.forceValues(end-1:end));
                  figure(3);  % Always use Figure 3 (outside of the GUI)
                  %----- Plot the force and objective function output
                  set(gcf, 'Name', 'Optimization', 'NumberTitle', 'off');  % Set custom name and hide 'Figure 3' title
                  subplot(2,1,1)
                  plot(linspace(1,length(app.forceValues),length(app.forceValues)),app.forceValues,'ro','Linewidth',2)
                  ylabel ('Force values')
                  subplot(2,1,2)
                  plot(linspace(1,length(app.forceValues),length(app.forceValues)),app.of_values,'ro','Linewidth',2)
                  xlabel ('Iteration')
                  ylabel ('Objective function output')
                end
            end
            %----- Display Metrics
            %----- Select wich variables you want to output and visualize
            if strcmp(app.selection,'Both')
                app.TotalAbsdiffLabel.Enable = 'on';
                app.TotalEDLabel.Enable = 'on';
                app.TotalSSVLabel.Enable = 'on';
                app.TotalAbsdiffLabel.Text = sprintf("Total Abs. diff.: %.2f",round(total_abs_diff,2));
                app.TotalEDLabel.Text = sprintf("Total ED: %.2f",round(total_eu_dist,2));
                app.TotalSSVLabel.Text = sprintf("Total SSV: %.2f",round(total_ssv,2));
                % Objective function
                app.Objectivefunction.Text = sprintf("%.2f",cf);
                if app.iteration > 1 && app.gd_optCheckBox.Value == 1
                    app.next_output.Text = sprintf("%.2f",next_value);
                end
            elseif strcmp(app.selection,'Estimate H')
                app.TotalAbsdiffLabel.Enable = 'off';
                app.TotalEDLabel.Text = sprintf("Total ED: %.2f",round(total_eu_dist,2));
                app.TotalSSVLabel.Enable = 'off';
            elseif strcmp(app.selection,'Estimate W')
                app.TotalAbsdiffLabel.Enable = 'on';
                app.TotalSSVLabel.Enable = 'on';
                app.TotalAbsdiffLabel.Text = sprintf("Total Abs. diff.: %.2f",round(total_abs_diff,2));
                app.TotalEDLabel.Enable = 'off';
                app.TotalSSVLabel.Text = sprintf("Total SSV: %.2f",round(total_ssv,2));
                % Objective function
                app.Objectivefunction.Text = sprintf("%.2f",cf);                
            end
            
        end

        % Callback function
        function forceSliderValueChanged(app, event)
            app.forceSlider.Value;
        end

        % Callback function
        function gd_optCheckBoxValueChanged(app, event)
            app.gd_optCheckBox.Value;
%           disp(gd_value)
        end

        % Callback function
        function CalculateMVCMenuSelected(app, event)
            
        end

        % Menu selected function: LoadreferencesynergiesMenu
        function LoadreferencesynergiesMenuSelected(app, event)
            % Prompt user to select a file
            [filenames, filepath] = uigetfile({'*.mat'}, 'Select Data Files', 'MultiSelect', 'on');
            
            % Check if the user canceled file selection
            if isequal(filenames, 0)
                % User canceled, do nothing
                return;
            end
            % If only one file is selected, filenames will be a char, otherwise a cell array
            if ischar(filenames)
                % User selected only one file
                filenames = {filenames};  % Convert to cell array for consistency
            end
            
            %----- Check that exactly two files were selected
            %----- The mean_W and mean_H have to be uploaded at the same
            %----- time
            if numel(filenames) ~= 2
                errordlg('Please select exactly two files.');  % Display an error dialog if not two files
                return;
            end
            %----- Load the selected files
            app.mean_H_gs = load(fullfile(filepath, filenames{1})).mean_H_gs;
            app.mean_W_gs = load(fullfile(filepath, filenames{2})).mean_W_gs;

            app.fileLabel.Text = sprintf("Reference synergies loaded");    
            app.fileLabel.FontWeight = 'bold';
            
            %--------------- Plot the reference synergies
            app.names_muscles = categorical(["TP";"PECM";"DA";"DM";"DP";"BIC";"TRI"]);
            app.names_muscles = reordercats(app.names_muscles,["TP";"PECM";"DA";"DM";"DP";"BIC";"TRI"]);
            t = linspace(0,100,size(app.mean_H_gs,1));
            lineColor = 'k';
            lineWidth = 2;
            figure('Name','Reference synergies')
            
            c = 2;
            c1 = 1;
            for i = 1:size(app.mean_W_gs, 2)
                %----- Left column - Reference Synergy Vectors (Bar Plot)
                subplot(size(app.mean_W_gs, 2), 2, c1);
                bar(app.names_muscles, app.mean_W_gs(:, i));
                
                %----- Customize bar appearance
                h1 = bar(app.names_muscles, app.mean_W_gs(:, i));
                set(h1, 'EdgeColor', lineColor, 'LineWidth', lineWidth, 'FaceColor', 'none');
                
                %----- Set y-axis label dynamically as W_1, W_2, ...
                ylabel(['W_', num2str(i)]);
                ylim([0, 1]);
                
                % Add title only for the first row of subplots
                if i == 1
                    title('Reference Synergy Vectors');
                end
                
                %----- Right column - Reference Coefficients of Activation (Line Plot)
                subplot(size(app.mean_H_gs, 2), 2, c);
                plot(t, app.mean_H_gs(:, i), 'k', 'LineWidth', 2);
                
                % Set y-axis label dynamically as C_1, C_2, ...
                ylabel(['C_', num2str(i)]);
                ylim([0, 1]);
                
                %----- Add title only for the first row of subplots
                if i == 1
                    title('Reference Coefficients of Activation');
                end
                
                % Update subplot positions for the next iteration
                c1 = c1 + 2;
                c = c + 2;
                if i == 3
                    xlabel 'Time (Normalized)'
                end
            end
        end

        % Value changed function: ForceSpinner
        function ForceSpinnerValueChanged(app, event)
            app.ForceSpinner.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MusclesynergybasedanalysisGUIUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {437, 437, 437};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {437, 437};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {207, '1x', 154};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MusclesynergybasedanalysisGUIUIFigure = uifigure('Visible', 'off');
            app.MusclesynergybasedanalysisGUIUIFigure.Name = 'Muscle-synergy based analysis GUI';
            app.MusclesynergybasedanalysisGUIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MusclesynergybasedanalysisGUIUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CalculatemusclesynergiesButton = uibutton(app.LeftPanel, 'push');
            app.CalculatemusclesynergiesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatesynergiesButtonPushed, true);
            app.CalculatemusclesynergiesButton.Text = 'Calculate muscle synergies';
            app.fileLabel = uilabel(app.LeftPanel);
            app.fileLabel.Text = 'No file loaded';
            app.OutputsButtonGroup = uibuttongroup(app.LeftPanel);
            app.OutputsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OutputsButtonGroupSelectionChanged, true);
            app.OutputsButtonGroup.Title = 'Outputs';
            app.EstimateHButton = uiradiobutton(app.OutputsButtonGroup);
            app.EstimateHButton.Text = 'Estimate H';
            app.EstimateWButton = uiradiobutton(app.OutputsButtonGroup);
            app.EstimateWButton.Text = 'Estimate W';
            app.BothButton = uiradiobutton(app.OutputsButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.OUTPUTSLabel = uilabel(app.LeftPanel);
            app.OUTPUTSLabel.Text = 'OUTPUTS';
            app.TotalAbsdiffLabel = uilabel(app.LeftPanel);
            app.TotalAbsdiffLabel.Text = 'Total Abs. diff.:';
            app.TotalSSVLabel = uilabel(app.LeftPanel);
            app.TotalSSVLabel.Text = 'Total SSV:';
            app.TotalEDLabel = uilabel(app.LeftPanel);
            app.TotalEDLabel.Text = 'Total ED:';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.SSVWestTab = uitab(app.TabGroup);
            app.SSVWestTab.Title = 'SSV (W est)';
            app.UIAxes = uiaxes(app.SSVWestTab);
            title(app.UIAxes, 'Cosine similarity between synergies')
            xlabel(app.UIAxes, 'Measured synergies')
            ylabel(app.UIAxes, 'Reference synergies')
            app.DiffWestTab = uitab(app.TabGroup);
            app.DiffWestTab.Title = 'Diff (W est)';
            app.UIAxes_2 = uiaxes(app.DiffWestTab);
            title(app.UIAxes_2, 'Absolute difference to reference synergies')
            ylabel(app.UIAxes_2, 'Abs. diff')
            app.UIAxes_2.XLim = [0 3];
            app.UIAxes_2.XTick = [0 1 2 3];
            app.UIAxes_2.XTickLabel = {'0'; '1'; '2'; '3'};
            app.EDHestTab = uitab(app.TabGroup);
            app.EDHestTab.Title = 'ED (H est)';
            app.UIAxes_5 = uiaxes(app.EDHestTab);
            title(app.UIAxes_5, 'Synergy vectors')
            ylabel(app.UIAxes_5, 'S_2')
            app.UIAxes_5.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.UIAxes_6 = uiaxes(app.EDHestTab);
            title(app.UIAxes_6, 'Synergy vectors')
            ylabel(app.UIAxes_6, 'S_3')
            app.UIAxes_6.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.UIAxes_4 = uiaxes(app.EDHestTab);
            title(app.UIAxes_4, 'Synergy vectors')
            ylabel(app.UIAxes_4, 'S_1')
            app.UIAxes_4.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.RightPanel = uipanel(app.GridLayout);
            app.COSTFUNCTIONLabel = uilabel(app.RightPanel);
            app.COSTFUNCTIONLabel.Text = 'COST FUNCTION';
            app.Objectivefunction = uilabel(app.RightPanel);
            app.Objectivefunction.Text = '';
            app.next_output = uilabel(app.RightPanel);
            app.next_output.Text = '';
            app.ForceSpinnerLabel = uilabel(app.RightPanel);
            app.ForceSpinnerLabel.Text = 'Force';
            app.ForceSpinner = uispinner(app.RightPanel);
            app.ForceSpinner.ValueChangedFcn = createCallbackFcn(app, @ForceSpinnerValueChanged, true);
            app.NEXTVALUELabel = uilabel(app.RightPanel);
            app.NEXTVALUELabel.Text = 'NEXT VALUE';
            app.FOROPTIMIZATIONLabel = uilabel(app.RightPanel);
            app.FOROPTIMIZATIONLabel.Text = 'FOR OPTIMIZATION';
            app.gd_optCheckBox = uicheckbox(app.RightPanel);
            app.gd_optCheckBox.Text = 'Gradient descent';
            app.LoadreferencesynergiesMenu = uimenu(app.MusclesynergybasedanalysisGUIUIFigure);
            app.LoadreferencesynergiesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadreferencesynergiesMenuSelected, true);
            app.LoadreferencesynergiesMenu.Text = 'Load reference synergies';
            app.LoadnewtrialMenu = uimenu(app.MusclesynergybasedanalysisGUIUIFigure);
            app.LoadnewtrialMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadnewtrialMenuSelected, true);
            app.LoadnewtrialMenu.Text = 'Load new trial';
            % Show the figure after all components are created
            app.MusclesynergybasedanalysisGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_muscle_synergies

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MusclesynergybasedanalysisGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MusclesynergybasedanalysisGUIUIFigure)
        end
    end
end